classdef SignalFlowEEG < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SignalFlowEEGUIFigure           matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        LoadMenu                        matlab.ui.container.Menu
        SaveMenu                        matlab.ui.container.Menu
        SaveAsMenu                      matlab.ui.container.Menu
        QuitMenu                        matlab.ui.container.Menu
        SignalFlowVersionLabel          matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        SetupTab                        matlab.ui.container.Tab
        OpentheVHTPImportToolButton     matlab.ui.control.Button
        EEGImportToolLabel              matlab.ui.control.Label
        AuthorEditField                 matlab.ui.control.EditField
        AuthorEditFieldLabel            matlab.ui.control.Label
        DescriptionEditField            matlab.ui.control.EditField
        DescriptionEditFieldLabel       matlab.ui.control.Label
        TitleEditField                  matlab.ui.control.EditField
        TitleEditFieldLabel             matlab.ui.control.Label
        SaveButton                      matlab.ui.control.Button
        EditProjectInformationLabel     matlab.ui.control.Label
        SetupHtmlArea                   matlab.ui.control.HTML
        SetupFileListTree               matlab.ui.container.Tree
        ViewProjectFolderContentsLabel  matlab.ui.control.Label
        SetupLabel_2                    matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        SetupProjectFoldersLabel        matlab.ui.control.Label
        PathSetupPanel                  matlab.ui.container.Panel
        PathPanel                       matlab.ui.container.Panel
        ResetFoldersButton              matlab.ui.control.Button
        AddCustomFolderButton           matlab.ui.control.Button
        PluginSetupPanel                matlab.ui.container.Panel
        PluginPanel                     matlab.ui.container.Panel
        AddCustomPathButton             matlab.ui.control.Button
        ResetToolboxesButton            matlab.ui.control.Button
        SetupProjectToolboxesLabel      matlab.ui.control.Label
        BuilderTab                      matlab.ui.container.Tab
        ModuleSearchClearButton         matlab.ui.control.Button
        ModuleSearchButton              matlab.ui.control.Button
        ModuleSearchEditField           matlab.ui.control.EditField
        BuilderModuleFilterDropDown     matlab.ui.control.DropDown
        BuilderRefreshUserScripts       matlab.ui.control.Hyperlink
        BuilderHtmlArea                 matlab.ui.control.HTML
        FilterDropDownLabel             matlab.ui.control.Label
        ModuleInformationLabel          matlab.ui.control.Label
        ModuleInformation               matlab.ui.container.Panel
        CurrentFolderEditField          matlab.ui.control.EditField
        CurrentFolderLabel              matlab.ui.control.Label
        OutputFolderResetButton         matlab.ui.control.Button
        ModuleTypeEditField             matlab.ui.control.EditField
        ModuleTypeEditFieldLabel        matlab.ui.control.Label
        ViewModuleCodeButton            matlab.ui.control.Button
        ViewSourceCodeLabel             matlab.ui.control.Label
        OutputFolderTagDropDown         matlab.ui.control.DropDown
        OutputFolderTagDropDownLabel    matlab.ui.control.Label
        ModuleNameEditField             matlab.ui.control.EditField
        DescriptionLabelEditFieldLabel  matlab.ui.control.Label
        CurrentModuleLabel              matlab.ui.control.Label
        UserSourceTree                  matlab.ui.container.Tree
        UserModuleLibraryLabel          matlab.ui.control.Label
        ResetPipelineButton             matlab.ui.control.Button
        BuilderLabel                    matlab.ui.control.Label
        Image_2                         matlab.ui.control.Image
        DeleteFunctionButton            matlab.ui.control.Button
        AddFunctionButton               matlab.ui.control.Button
        PipelineBuilderLabel            matlab.ui.control.Label
        BaseModuleLibraryLabel          matlab.ui.control.Label
        moveDownButton                  matlab.ui.control.Button
        moveUpButton                    matlab.ui.control.Button
        TargetTree                      matlab.ui.container.Tree
        SourceTree                      matlab.ui.container.Tree
        ExecuteTab                      matlab.ui.container.Tab
        ExecuteHtmlArea                 matlab.ui.control.HTML
        WorkflowStatusLabel             matlab.ui.control.Label
        FileCatalogLabel                matlab.ui.control.Label
        DatasetActionsLabel             matlab.ui.control.Label
        Panel_2                         matlab.ui.container.Panel
        OpenExportFolderButton          matlab.ui.control.Button
        ViewChannelSpectraButton        matlab.ui.control.Button
        ViewElectrodeMapButton          matlab.ui.control.Button
        PlotDataButton                  matlab.ui.control.Button
        OpeninEEGLABButton              matlab.ui.control.Button
        OpenImportFolderButton          matlab.ui.control.Button
        ExecuteTree                     matlab.ui.container.Tree
        ProjectExecuteLabel             matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        ExecuteParallelButton           matlab.ui.control.Button
        ExecuteLoopButton               matlab.ui.control.Button
        ExecuteFileButton               matlab.ui.control.Button
        RefreshButton                   matlab.ui.control.Button
        ExecuteLabel                    matlab.ui.control.Label
        Image_3                         matlab.ui.control.Image
        ExecuteWorkflowTree             matlab.ui.container.Tree
        BrowseTab                       matlab.ui.container.Tab
        BrowseHtmlArea                  matlab.ui.control.HTML
        DatasetActionsLabel_2           matlab.ui.control.Label
        ProjectBrowserLabel             matlab.ui.control.Label
        PanelBrowsePicker               matlab.ui.container.Panel
        DropDownFolderPicker            matlab.ui.control.DropDown
        FilterDropDownLabel_2           matlab.ui.control.Label
        RefreshButton_2                 matlab.ui.control.Button
        FileCatalogLabel_2              matlab.ui.control.Label
        BrowseFileCatalog               matlab.ui.container.Tree
        PanelBrowseAction               matlab.ui.container.Panel
        CloseWindowsButton              matlab.ui.control.Button
        ButtonBrowseSpectra             matlab.ui.control.Button
        ButtonBrowseElectrode           matlab.ui.control.Button
        ButtonBrowsePlot                matlab.ui.control.Button
        ButtonBrowseEeglab              matlab.ui.control.Button
        OpenSelectedFolderButton        matlab.ui.control.Button
        BrowseLabel                     matlab.ui.control.Label
        Image_4                         matlab.ui.control.Image
    end


    properties (Access = private)
        
    end

    properties (Access = public)
        sfgClass                    % SignalFlowGUIClass
        PluginButtons               % Plugin buttons
        PluginLamps                 % Plugin success or fail
        PathButtons                 % Path buttons
        PathLamps                   % Path success or fail
        progressDialog              % Progress bar
        progress                    % Progress indicator

        guiListener                 % broadcast events to GUI
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.SignalFlowEEGUIFigure.Position(2) = 10; %centerPos;
            app.sfgClass = SignalFlowGUIClass();
            app.sfgClass = app.sfgClass.Startup(app);

        end

        % Button pushed function: moveUpButton
        function moveUpButtonPushed(app, event)
            app.sfgClass = app.sfgClass.moveModuleUp(app);
        end

        % Button pushed function: moveDownButton
        function moveDownButtonPushed(app, event)
            app.sfgClass = app.sfgClass.moveModuleDown(app);
        end

        % Selection changed function: SourceTree
        function SourceTreeSelectionChanged(app, event)
            app.sfgClass = app.sfgClass.treeSelect(app.SourceTree,app);
        end

        % Selection changed function: TargetTree
        function TargetTreeSelectionChanged(app, event)
            app.sfgClass = app.sfgClass.treeSelect(app.TargetTree,app);
        end

        % Selection changed function: ExecuteTree
        function ExecuteTreeSelectionChanged(app, event)
            app.sfgClass = app.sfgClass.treeSelect(app.ExecuteTree,app);
        end

        % Selection changed function: UserSourceTree
        function UserSourceTreeSelectionChanged(app, event)
            app.sfgClass = app.sfgClass.treeSelect(app.UserSourceTree,app);
        end

        % Button pushed function: AddFunctionButton
        function AddFunctionButtonPushed(app, event)
            app.sfgClass = app.sfgClass.addModule(app);
        end

        % Button pushed function: DeleteFunctionButton
        function DeleteFunctionButtonPushed(app, event)
            app.sfgClass = app.sfgClass.deleteModule(app);
        end

        % Menu selected function: LoadMenu
        function LoadMenuSelected(app, event)
            app.sfgClass = app.sfgClass.loadProject(app);
        end

        % Menu selected function: SaveMenu
        function SaveMenuSelected(app, event)
            app.sfgClass = app.sfgClass.saveProject(app);
        end

        % Menu selected function: SaveAsMenu
        function SaveAsMenuSelected(app, event)
            app.sfgClass = app.sfgClass.saveAsProject(app);
        end

        % Menu selected function: QuitMenu
        function QuitMenuSelected(app, event)
            app.sfgClass.shutdown(app);
        end

        % Close request function: SignalFlowEEGUIFigure
        function SignalFlowEEGUIFigureCloseRequest(app, event)
            app.sfgClass.shutdown(app);
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            app.sfgClass.refreshTrees(app);
        end

        % Button pushed function: OpenImportFolderButton
        function OpenImportFolderButtonPushed(app, event)
            app.sfgClass.openInputFolder(app);
        end

        % Button pushed function: OpeninEEGLABButton
        function OpeninEEGLABButtonPushed(app, event)
            app.sfgClass.openInEEGLAB(app);
        end

        % Button pushed function: PlotDataButton
        function PlotDataButtonPushed(app, event)
            app.sfgClass.plotData(app);
        end

        % Button pushed function: ViewElectrodeMapButton
        function ViewElectrodeMapButtonPushed(app, event)
            app.sfgClass.viewElectrodeMap(app);
        end

        % Button pushed function: ViewChannelSpectraButton
        function ViewChannelSpectraButtonPushed(app, event)
            app.sfgClass = app.sfgClass.viewChannelSpectra(app);
        end

        % Button pushed function: ExecuteFileButton
        function ExecuteFileButtonPushed(app, event)
            app.sfgClass = app.sfgClass.ExecuteFile(app);
        end

        % Button pushed function: ExecuteLoopButton
        function ExecuteLoopButtonPushed(app, event)
            app.sfgClass = app.sfgClass.ExecuteLoop(app);
        end

        % Button pushed function: ExecuteParallelButton
        function ExecuteParallelButtonPushed(app, event)
            app.sfgClass = app.sfgClass.ExecuteParallel(app);
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            app.sfgClass.TabChanged(app);
        end

        % Callback function
        function ForceQuitMenuSelected(app, event)
            app.sfgClass = app.sfgClass.forceshutdown(app);
        end

        % Button pushed function: ResetToolboxesButton
        function ResetToolboxesButtonPushed(app, event)
            app.sfgClass = app.sfgClass.ResetToolboxesButtonPushed(app);
        end

        % Button pushed function: AddCustomFolderButton
        function AddCustomFolderButtonPushed(app, event)
            app.sfgClass = app.sfgClass.AddCustomFolderButtonPushed(app);
        end

        % Button pushed function: ResetFoldersButton
        function ResetFoldersButtonPushed(app, event)
            app.sfgClass = app.sfgClass.ResetFoldersButtonPushed(app);
        end

        % Button pushed function: ViewModuleCodeButton
        function ViewModuleCodeButtonPushed(app, event)
            app.sfgClass = app.sfgClass.ViewModuleCodeButtonPushed(app);
        end

        % Button pushed function: ResetPipelineButton
        function ResetPipelineButtonPushed(app, event)
            app.sfgClass = app.sfgClass.resetPipeline(app);
        end

        % Button pushed function: OpenExportFolderButton
        function OpenExportFolderButtonPushed(app, event)
            app.sfgClass.openExportFolder(app);
        end

        % Callback function: BuilderRefreshUserScripts
        function BuilderRefreshUserScriptsClicked(app, event)
            app.sfgClass.refreshUserModuleFiles(app);
        end

        % Value changed function: BuilderModuleFilterDropDown
        function BuilderModuleFilterDropDownValueChanged(app, event)
            value = app.BuilderModuleFilterDropDown.Value;
            disp(value);
            app.sfgClass.refreshTrees(app);
        end

        % Value changed function: DropDownFolderPicker
        function DropDownFolderPickerValueChanged(app, event)
            app.sfgClass.refreshBrowseFileCatalog(app);
        end

        % Button pushed function: RefreshButton_2
        function RefreshButton_2Pushed(app, event)
            app.sfgClass.refreshBrowseFileCatalog(app);
        end

        % Button pushed function: ButtonBrowseEeglab
        function ButtonBrowseEeglabPushed(app, event)
            app.sfgClass.BrowseHandler(app, 'OpenInEeglab');
        end

        % Button pushed function: ButtonBrowsePlot
        function ButtonBrowsePlotPushed(app, event)
            app.sfgClass.BrowseHandler(app, 'PlotTimeSeries');
        end

        % Button pushed function: OpenSelectedFolderButton
        function OpenSelectedFolderButtonPushed(app, event)
            app.sfgClass.BrowseHandler(app, 'OpenFolder', app.BrowseFileCatalog.SelectedNodes.NodeData);

        end

        % Button pushed function: ButtonBrowseElectrode
        function ButtonBrowseElectrodePushed(app, event)
            app.sfgClass.BrowseHandler(app, 'ViewChannel');
        end

        % Button pushed function: ButtonBrowseSpectra
        function ButtonBrowseSpectraPushed(app, event)
            app.sfgClass.BrowseHandler(app, 'PlotSpectrogram')
        end

        % Button pushed function: CloseWindowsButton
        function CloseWindowsButtonPushed(app, event)
            close all;
        end

        % Selection changed function: BrowseFileCatalog
        function BrowseFileCatalogSelectionChanged(app, event)
             app.sfgClass = app.sfgClass.treeSelect(app.BrowseFileCatalog,app);           
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            app.sfgClass = app.sfgClass.setupEditProjectInformationSave(app);
        end

        % Button pushed function: OpentheVHTPImportToolButton
        function OpentheVHTPImportToolButtonPushed(app, event)

            % Instantiate the myimport app and run it
            vhtpImportGui;

        end

        % Button pushed function: ModuleSearchClearButton
        function ModuleSearchClearButtonPushed(app, event)
            app.ModuleSearchEditField.Value = '';
            app.sfgClass.moduleSearchButtonPushed(app);
        end

        % Button pushed function: ModuleSearchButton
        function ModuleSearchButtonPushed(app, event)
            app.sfgClass.moduleSearchButtonPushed(app);
        end

        % Value changed function: OutputFolderTagDropDown
        function OutputFolderTagDropDownValueChanged(app, event)
            app.sfgClass = app.sfgClass.moduleChangePropertiesConfirm(app);
        end

        % Button pushed function: OutputFolderResetButton
        function OutputFolderResetButtonPushed(app, event)
            app.sfgClass = app.sfgClass.moduleChangePropertiesDefault(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.SignalFlowEEGUIFigure = uifigure('Visible', 'off');
            app.SignalFlowEEGUIFigure.Name = 'SignalFlowEEG';
            app.SignalFlowEEGUIFigure.Icon = 'sflogo.png';
            app.SignalFlowEEGUIFigure.CloseRequestFcn = createCallbackFcn(app, @SignalFlowEEGUIFigureCloseRequest, true);
            app.FileMenu = uimenu(app.SignalFlowEEGUIFigure);
            app.FileMenu.Text = 'File';
            app.LoadMenu = uimenu(app.FileMenu);
            app.LoadMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadMenuSelected, true);
            app.LoadMenu.Text = 'Load';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected, true);
            app.SaveMenu.Text = 'Save';
            app.SaveAsMenu = uimenu(app.FileMenu);
            app.SaveAsMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveAsMenuSelected, true);
            app.SaveAsMenu.Text = 'Save As';
            app.QuitMenu = uimenu(app.FileMenu);
            app.QuitMenu.MenuSelectedFcn = createCallbackFcn(app, @QuitMenuSelected, true);
            app.QuitMenu.Text = 'Quit';
            app.TabGroup = uitabgroup(app.SignalFlowEEGUIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.SetupTab = uitab(app.TabGroup);
            app.SetupTab.Title = 'Setup';
            app.SetupProjectToolboxesLabel = uilabel(app.SetupTab);
            app.SetupProjectToolboxesLabel.Text = 'Setup Project Toolboxes';
            app.PluginSetupPanel = uipanel(app.SetupTab);
            app.ResetToolboxesButton = uibutton(app.PluginSetupPanel, 'push');
            app.ResetToolboxesButton.ButtonPushedFcn = createCallbackFcn(app, @ResetToolboxesButtonPushed, true);
            app.ResetToolboxesButton.Text = 'Reset Toolboxes';
            app.AddCustomPathButton = uibutton(app.PluginSetupPanel, 'push');
            app.AddCustomPathButton.Enable = 'off';
            app.AddCustomPathButton.Visible = 'off';
            app.AddCustomPathButton.Text = 'Add Custom Path';
            app.PluginPanel = uipanel(app.PluginSetupPanel);
            app.PathSetupPanel = uipanel(app.SetupTab);
            app.AddCustomFolderButton = uibutton(app.PathSetupPanel, 'push');
            app.AddCustomFolderButton.ButtonPushedFcn = createCallbackFcn(app, @AddCustomFolderButtonPushed, true);
            app.AddCustomFolderButton.Text = 'Add Custom Folder';
            app.ResetFoldersButton = uibutton(app.PathSetupPanel, 'push');
            app.ResetFoldersButton.ButtonPushedFcn = createCallbackFcn(app, @ResetFoldersButtonPushed, true);
            app.ResetFoldersButton.Text = 'Reset Folders';
            app.PathPanel = uipanel(app.PathSetupPanel);
            app.SetupProjectFoldersLabel = uilabel(app.SetupTab);
            app.SetupProjectFoldersLabel.Text = 'Setup Project Folders';
            app.Image = uiimage(app.SetupTab);
            app.Image.ImageSource = 'sflogo.png';
            app.SetupLabel_2 = uilabel(app.SetupTab);
            app.SetupLabel_2.Text = 'Setup';
            app.ViewProjectFolderContentsLabel = uilabel(app.SetupTab);
            app.ViewProjectFolderContentsLabel.Text = 'View Project Folder Contents';
            app.SetupFileListTree = uitree(app.SetupTab);
            app.SetupHtmlArea = uihtml(app.SetupTab);
            app.EditProjectInformationLabel = uilabel(app.SetupTab);
            app.EditProjectInformationLabel.Text = 'Edit Project Information';
            app.SaveButton = uibutton(app.SetupTab, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.TitleEditFieldLabel = uilabel(app.SetupTab);
            app.TitleEditFieldLabel.Text = 'Title:';
            app.TitleEditField = uieditfield(app.SetupTab, 'text');
            app.TitleEditField.CharacterLimits = [1 255];
            app.DescriptionEditFieldLabel = uilabel(app.SetupTab);
            app.DescriptionEditFieldLabel.Text = 'Description:';
            app.DescriptionEditField = uieditfield(app.SetupTab, 'text');
            app.DescriptionEditField.CharacterLimits = [1 255];
            app.AuthorEditFieldLabel = uilabel(app.SetupTab);
            app.AuthorEditFieldLabel.Text = 'Author:';
            app.AuthorEditField = uieditfield(app.SetupTab, 'text');
            app.AuthorEditField.CharacterLimits = [1 255];
            app.EEGImportToolLabel = uilabel(app.SetupTab);
            app.EEGImportToolLabel.Text = 'EEG Import Tool';
            app.OpentheVHTPImportToolButton = uibutton(app.SetupTab, 'push');
            app.OpentheVHTPImportToolButton.ButtonPushedFcn = createCallbackFcn(app, @OpentheVHTPImportToolButtonPushed, true);
            app.OpentheVHTPImportToolButton.Text = 'Open the VHTP Import Tool';
            app.BuilderTab = uitab(app.TabGroup);
            app.BuilderTab.Title = 'Builder';
            app.SourceTree = uitree(app.BuilderTab);
            app.SourceTree.SelectionChangedFcn = createCallbackFcn(app, @SourceTreeSelectionChanged, true);
            app.TargetTree = uitree(app.BuilderTab);
            app.TargetTree.SelectionChangedFcn = createCallbackFcn(app, @TargetTreeSelectionChanged, true);
            app.TargetTree.Tag = 'Target';
            app.moveUpButton = uibutton(app.BuilderTab, 'push');
            app.moveUpButton.ButtonPushedFcn = createCallbackFcn(app, @moveUpButtonPushed, true);
            app.moveUpButton.Text = '/\';
            app.moveDownButton = uibutton(app.BuilderTab, 'push');
            app.moveDownButton.ButtonPushedFcn = createCallbackFcn(app, @moveDownButtonPushed, true);
            app.moveDownButton.Text = 'V';
            app.BaseModuleLibraryLabel = uilabel(app.BuilderTab);
            app.BaseModuleLibraryLabel.Text = 'Base Module Library';
            app.PipelineBuilderLabel = uilabel(app.BuilderTab);
            app.PipelineBuilderLabel.Text = 'Pipeline Builder';
            app.AddFunctionButton = uibutton(app.BuilderTab, 'push');
            app.AddFunctionButton.ButtonPushedFcn = createCallbackFcn(app, @AddFunctionButtonPushed, true);
            app.AddFunctionButton.Text = 'Add Module';
            app.DeleteFunctionButton = uibutton(app.BuilderTab, 'push');
            app.DeleteFunctionButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteFunctionButtonPushed, true);
            app.DeleteFunctionButton.Text = 'Delete Module';
            app.Image_2 = uiimage(app.BuilderTab);
            app.Image_2.ImageSource = 'sflogo.png';
            app.BuilderLabel = uilabel(app.BuilderTab);
            app.BuilderLabel.Text = 'Builder';
            app.ResetPipelineButton = uibutton(app.BuilderTab, 'push');
            app.ResetPipelineButton.ButtonPushedFcn = createCallbackFcn(app, @ResetPipelineButtonPushed, true);
            app.ResetPipelineButton.Text = 'Reset Pipeline';
            app.UserModuleLibraryLabel = uilabel(app.BuilderTab);
            app.UserModuleLibraryLabel.Text = 'User Module Library';
            app.UserSourceTree = uitree(app.BuilderTab);
            app.UserSourceTree.SelectionChangedFcn = createCallbackFcn(app, @UserSourceTreeSelectionChanged, true);
            app.ModuleInformation = uipanel(app.BuilderTab);
            app.CurrentModuleLabel = uilabel(app.ModuleInformation);
            app.CurrentModuleLabel.Text = 'Current Module';
            app.DescriptionLabelEditFieldLabel = uilabel(app.ModuleInformation);
            app.DescriptionLabelEditFieldLabel.Text = 'Module Name:';
            app.ModuleNameEditField = uieditfield(app.ModuleInformation, 'text');
            app.ModuleNameEditField.Editable = 'off';
            app.OutputFolderTagDropDownLabel = uilabel(app.ModuleInformation);
            app.OutputFolderTagDropDownLabel.Visible = 'off';
            app.OutputFolderTagDropDownLabel.Text = 'Output Folder Tag:';
            app.OutputFolderTagDropDown = uidropdown(app.ModuleInformation);
            app.OutputFolderTagDropDown.Editable = 'on';
            app.OutputFolderTagDropDown.ValueChangedFcn = createCallbackFcn(app, @OutputFolderTagDropDownValueChanged, true);
            app.OutputFolderTagDropDown.Visible = 'off';
            app.ViewSourceCodeLabel = uilabel(app.ModuleInformation);
            app.ViewSourceCodeLabel.Text = 'View Source Code';
            app.ViewModuleCodeButton = uibutton(app.ModuleInformation, 'push');
            app.ViewModuleCodeButton.ButtonPushedFcn = createCallbackFcn(app, @ViewModuleCodeButtonPushed, true);
            app.ViewModuleCodeButton.Text = 'View Module Code';
            app.ModuleTypeEditFieldLabel = uilabel(app.ModuleInformation);
            app.ModuleTypeEditFieldLabel.Text = 'Module Type:';
            app.ModuleTypeEditField = uieditfield(app.ModuleInformation, 'text');
            app.ModuleTypeEditField.Editable = 'off';
            app.OutputFolderResetButton = uibutton(app.ModuleInformation, 'push');
            app.OutputFolderResetButton.ButtonPushedFcn = createCallbackFcn(app, @OutputFolderResetButtonPushed, true);
            app.OutputFolderResetButton.Icon = fullfile(pathToMLAPP, 'icons', 'reset.png');
            app.OutputFolderResetButton.Text = '';
            app.CurrentFolderLabel = uilabel(app.ModuleInformation);
            app.CurrentFolderLabel.Text = 'Current Folder:';
            app.CurrentFolderEditField = uieditfield(app.ModuleInformation, 'text');
            app.CurrentFolderEditField.Editable = 'off';
            app.ModuleInformationLabel = uilabel(app.BuilderTab);
            app.ModuleInformationLabel.Text = 'Module Information';
            app.FilterDropDownLabel = uilabel(app.BuilderTab);
            app.FilterDropDownLabel.Text = 'Filter :';
            app.BuilderHtmlArea = uihtml(app.BuilderTab);
            app.BuilderRefreshUserScripts = uihyperlink(app.BuilderTab);
            app.BuilderRefreshUserScripts.HyperlinkClickedFcn = createCallbackFcn(app, @BuilderRefreshUserScriptsClicked, true);
            app.BuilderRefreshUserScripts.Text = 'Refresh';
            app.BuilderModuleFilterDropDown = uidropdown(app.BuilderTab);
            app.BuilderModuleFilterDropDown.Items = {'all', 'inflow', 'midflow', 'outflow'};
            app.BuilderModuleFilterDropDown.ItemsData = {'all', 'inflow', 'midflow', 'outflow'};
            app.BuilderModuleFilterDropDown.ValueChangedFcn = createCallbackFcn(app, @BuilderModuleFilterDropDownValueChanged, true);
            app.BuilderModuleFilterDropDown.Value = 'all';
            app.ModuleSearchEditField = uieditfield(app.BuilderTab, 'text');
            app.ModuleSearchEditField.Placeholder = 'Search for Module';
            app.ModuleSearchButton = uibutton(app.BuilderTab, 'push');
            app.ModuleSearchButton.ButtonPushedFcn = createCallbackFcn(app, @ModuleSearchButtonPushed, true);
            app.ModuleSearchButton.Icon = fullfile(pathToMLAPP, 'icons', 'search.png');
            app.ModuleSearchButton.Text = '';
            app.ModuleSearchClearButton = uibutton(app.BuilderTab, 'push');
            app.ModuleSearchClearButton.ButtonPushedFcn = createCallbackFcn(app, @ModuleSearchClearButtonPushed, true);
            app.ModuleSearchClearButton.Icon = fullfile(pathToMLAPP, 'icons', 'clear.png');
            app.ModuleSearchClearButton.Text = '';
            app.ExecuteTab = uitab(app.TabGroup);
            app.ExecuteTab.Title = 'Execute';
            app.ExecuteWorkflowTree = uitree(app.ExecuteTab);
            app.ExecuteWorkflowTree.Tag = 'Execute';
            app.Image_3 = uiimage(app.ExecuteTab);
            app.Image_3.ImageSource = 'sflogo.png';
            app.ExecuteLabel = uilabel(app.ExecuteTab);
            app.ExecuteLabel.Text = 'Execute';
            app.Panel = uipanel(app.ExecuteTab);
            app.RefreshButton = uibutton(app.Panel, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.ExecuteFileButton = uibutton(app.Panel, 'push');
            app.ExecuteFileButton.ButtonPushedFcn = createCallbackFcn(app, @ExecuteFileButtonPushed, true);
            app.ExecuteFileButton.Enable = 'off';
            app.ExecuteFileButton.Text = 'Execute (File)';
            app.ExecuteLoopButton = uibutton(app.Panel, 'push');
            app.ExecuteLoopButton.ButtonPushedFcn = createCallbackFcn(app, @ExecuteLoopButtonPushed, true);
            app.ExecuteLoopButton.Enable = 'off';
            app.ExecuteLoopButton.Text = 'Execute (Loop)';
            app.ExecuteParallelButton = uibutton(app.Panel, 'push');
            app.ExecuteParallelButton.ButtonPushedFcn = createCallbackFcn(app, @ExecuteParallelButtonPushed, true);
            app.ExecuteParallelButton.Enable = 'off';
            app.ExecuteParallelButton.Text = 'Execute (Parallel)';
            app.ProjectExecuteLabel = uilabel(app.ExecuteTab);
            app.ProjectExecuteLabel.Text = 'Project Execute';
            app.ExecuteTree = uitree(app.ExecuteTab);
            app.ExecuteTree.SelectionChangedFcn = createCallbackFcn(app, @ExecuteTreeSelectionChanged, true);
            app.ExecuteTree.Tag = 'Execute';
            app.Panel_2 = uipanel(app.ExecuteTab);
            app.OpenImportFolderButton = uibutton(app.Panel_2, 'push');
            app.OpenImportFolderButton.ButtonPushedFcn = createCallbackFcn(app, @OpenImportFolderButtonPushed, true);
            app.OpenImportFolderButton.Text = 'Open Import Folder';
            app.OpeninEEGLABButton = uibutton(app.Panel_2, 'push');
            app.OpeninEEGLABButton.ButtonPushedFcn = createCallbackFcn(app, @OpeninEEGLABButtonPushed, true);
            app.OpeninEEGLABButton.Text = 'Open in EEGLAB';
            app.PlotDataButton = uibutton(app.Panel_2, 'push');
            app.PlotDataButton.ButtonPushedFcn = createCallbackFcn(app, @PlotDataButtonPushed, true);
            app.PlotDataButton.Text = 'Plot Data';
            app.ViewElectrodeMapButton = uibutton(app.Panel_2, 'push');
            app.ViewElectrodeMapButton.ButtonPushedFcn = createCallbackFcn(app, @ViewElectrodeMapButtonPushed, true);
            app.ViewElectrodeMapButton.Text = 'View Electrode Map';
            app.ViewChannelSpectraButton = uibutton(app.Panel_2, 'push');
            app.ViewChannelSpectraButton.ButtonPushedFcn = createCallbackFcn(app, @ViewChannelSpectraButtonPushed, true);
            app.ViewChannelSpectraButton.Text = 'View Channel Spectra';
            app.OpenExportFolderButton = uibutton(app.Panel_2, 'push');
            app.OpenExportFolderButton.ButtonPushedFcn = createCallbackFcn(app, @OpenExportFolderButtonPushed, true);
            app.OpenExportFolderButton.Text = 'Open Export Folder';
            app.DatasetActionsLabel = uilabel(app.ExecuteTab);
            app.DatasetActionsLabel.Text = 'Dataset Actions';
            app.FileCatalogLabel = uilabel(app.ExecuteTab);
            app.FileCatalogLabel.Text = 'File Catalog';
            app.WorkflowStatusLabel = uilabel(app.ExecuteTab);
            app.WorkflowStatusLabel.Text = 'Workflow Status';
            app.ExecuteHtmlArea = uihtml(app.ExecuteTab);
            app.BrowseTab = uitab(app.TabGroup);
            app.BrowseTab.Title = 'Browse';
            app.Image_4 = uiimage(app.BrowseTab);
            app.Image_4.ImageSource = 'sflogo.png';
            app.BrowseLabel = uilabel(app.BrowseTab);
            app.BrowseLabel.Text = 'Browse';
            app.PanelBrowseAction = uipanel(app.BrowseTab);
            app.OpenSelectedFolderButton = uibutton(app.PanelBrowseAction, 'push');
            app.OpenSelectedFolderButton.ButtonPushedFcn = createCallbackFcn(app, @OpenSelectedFolderButtonPushed, true);
            app.OpenSelectedFolderButton.Text = 'Open Selected Folder';
            app.ButtonBrowseEeglab = uibutton(app.PanelBrowseAction, 'push');
            app.ButtonBrowseEeglab.ButtonPushedFcn = createCallbackFcn(app, @ButtonBrowseEeglabPushed, true);
            app.ButtonBrowseEeglab.Text = 'Open in EEGLAB';
            app.ButtonBrowsePlot = uibutton(app.PanelBrowseAction, 'push');
            app.ButtonBrowsePlot.ButtonPushedFcn = createCallbackFcn(app, @ButtonBrowsePlotPushed, true);
            app.ButtonBrowsePlot.Text = 'Plot Data';
            app.ButtonBrowseElectrode = uibutton(app.PanelBrowseAction, 'push');
            app.ButtonBrowseElectrode.ButtonPushedFcn = createCallbackFcn(app, @ButtonBrowseElectrodePushed, true);
            app.ButtonBrowseElectrode.Text = 'View Electrode Map';
            app.ButtonBrowseSpectra = uibutton(app.PanelBrowseAction, 'push');
            app.ButtonBrowseSpectra.ButtonPushedFcn = createCallbackFcn(app, @ButtonBrowseSpectraPushed, true);
            app.ButtonBrowseSpectra.Text = 'View Channel Spectra';
            app.CloseWindowsButton = uibutton(app.PanelBrowseAction, 'push');
            app.CloseWindowsButton.ButtonPushedFcn = createCallbackFcn(app, @CloseWindowsButtonPushed, true);
            app.CloseWindowsButton.Text = 'Close Windows';
            app.BrowseFileCatalog = uitree(app.BrowseTab);
            app.BrowseFileCatalog.SelectionChangedFcn = createCallbackFcn(app, @BrowseFileCatalogSelectionChanged, true);
            app.BrowseFileCatalog.Tag = 'Execute';
            app.FileCatalogLabel_2 = uilabel(app.BrowseTab);
            app.FileCatalogLabel_2.Text = 'File Catalog';
            app.PanelBrowsePicker = uipanel(app.BrowseTab);
            app.RefreshButton_2 = uibutton(app.PanelBrowsePicker, 'push');
            app.RefreshButton_2.ButtonPushedFcn = createCallbackFcn(app, @RefreshButton_2Pushed, true);
            app.RefreshButton_2.Text = 'Refresh';
            app.FilterDropDownLabel_2 = uilabel(app.PanelBrowsePicker);
            app.FilterDropDownLabel_2.Text = 'Current Folder:';
            app.DropDownFolderPicker = uidropdown(app.PanelBrowsePicker);
            app.DropDownFolderPicker.Items = {};
            app.DropDownFolderPicker.ValueChangedFcn = createCallbackFcn(app, @DropDownFolderPickerValueChanged, true);
            app.DropDownFolderPicker.Value = {};
            app.ProjectBrowserLabel = uilabel(app.BrowseTab);
            app.ProjectBrowserLabel.Text = 'Project Browser';
            app.DatasetActionsLabel_2 = uilabel(app.BrowseTab);
            app.DatasetActionsLabel_2.Text = 'Dataset Actions';
            app.BrowseHtmlArea = uihtml(app.BrowseTab);
            app.SignalFlowVersionLabel = uilabel(app.SignalFlowEEGUIFigure);
            app.SignalFlowVersionLabel.Text = 'SignalFlowVersion';
            % Show the figure after all components are created
            app.SignalFlowEEGUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SignalFlowEEG

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SignalFlowEEGUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SignalFlowEEGUIFigure)
        end
    end
end